classdef perceive_gui_startup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LocalSettingsNameEditField  matlab.ui.control.EditField
        LocalSettingsNameEditFieldLabel  matlab.ui.control.Label
        CommandDisplay              matlab.ui.control.TextArea
        commandlineTextAreaLabel    matlab.ui.control.Label
        SessionEditField            matlab.ui.control.EditField
        subjectIDLabel_2            matlab.ui.control.Label
        SubjectIDEditField          matlab.ui.control.EditField
        subjectIDLabel              matlab.ui.control.Label
        GUIModeDropDown             matlab.ui.control.DropDown
        perceiveGUIDropDownLabel    matlab.ui.control.Label
        ToolboxLabel                matlab.ui.control.Label
        SelectedFilesLabel          matlab.ui.control.Label
        processfilesperceiveButton  matlab.ui.control.Button
        outputDir                   matlab.ui.control.EditField
        outputdirectoryLabel        matlab.ui.control.Label
        FilePickerValue             matlab.ui.control.Button
        ExtendedOutputDropDown      matlab.ui.control.DropDown
        ExtendedOutputLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        SelectedFilePaths % Stores the selected file paths
    end

    methods (Access = private)

        % Validate inputs before calling perceive
        function isValid = validateInputs(app)
            isValid = true;

            % Validate Subject ID (sub)
            sub = app.SubjectIDEditField.Value;
            if ~isempty(sub) && ~(ischar(sub) || iscell(sub) || isnumeric(sub))
                uialert(app.UIFigure, 'Subject ID must be a character array, cell array, or numeric.', 'Invalid Input');
                isValid = false;
            end

            % Validate Session (sesMedOffOn01)
            sesMedOffOn01 = app.SessionEditField.Value;
            validSessions = {"", "MedOff", "MedOn", "MedDaily", "MedOff01", "MedOn01", "MedOff02", "MedOn02", "MedOff03", "MedOn03", "MedOffOn01", "MedOffOn02", "MedOffOn03", "MedOnPostOpIPG", "MedOffPostOpIPG", "Unknown", "PostOp"};

            % Convert sesMedOffOn01 to a cell array if it's a character array
            if ischar(sesMedOffOn01) || isstring(sesMedOffOn01)
                sesMedOffOn01 = {sesMedOffOn01};
            end

            if ~ismember(sesMedOffOn01, validSessions)
                uialert(app.UIFigure, 'Invalid session value.', 'Invalid Input');
                isValid = false;
            end

            % Validate Extended Output (extended)
            extended = app.ExtendedOutputDropDown.Value;
            if ~ismember(extended, {"", "yes"})
                uialert(app.UIFigure, 'Extended Output must be "yes" or empty.', 'Invalid Input');
                isValid = false;
            end

            % Validate GUI (gui)
            gui = app.GUIModeDropDown.Value;
            if ~ismember(gui, {"", "yes"})
                uialert(app.UIFigure, 'GUI Mode must be "yes" or empty.', 'Invalid Input');
                isValid = false;
            end
        end


        % Helper function to update the command display
        function updateCommandDisplay(app)
            % Retrieve the current values from the input fields
            sub = app.SubjectIDEditField.Value;
            sesMedOffOn01 = app.SessionEditField.Value;
            extended = app.ExtendedOutputDropDown.Value;
            gui = app.GUIModeDropDown.Value;
            localsettings_name = app.LocalSettingsNameEditField.Value;

            % Construct the perceive command
            if isempty(app.SelectedFilePaths)
                filesArg = '""'; % Empty string for files
            elseif length(app.SelectedFilePaths) == 1
                filesArg = sprintf('"%s"', app.SelectedFilePaths{1});
            else
                % Join multiple files with spaces
                filesArg = strjoin(cellfun(@(f) sprintf('"%s"', f), app.SelectedFilePaths, 'UniformOutput', false), ' ');
            end

            % Construct the command string
            commandStr = sprintf('perceive.exe %s "%s" "%s" "%s"', ...
                filesArg, ...
                sub, ...
                sesMedOffOn01, ...
                extended);

            % Add gui and localsettings_name if they are not empty
            if ~isempty(gui) && ~strcmp(gui, "")
                commandStr = sprintf('%s "%s"', commandStr, gui);
            else
                commandStr = sprintf('%s ""', commandStr);
            end

            if ~isempty(localsettings_name) && ~strcmp(localsettings_name, "")
                commandStr = sprintf('%s "%s"', commandStr, localsettings_name);
            else
                commandStr = sprintf('%s ""', commandStr);
            end

            % Update the command display
            app.CommandDisplay.Value = commandStr;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initialize the stored file paths and inputs
            app.SelectedFilePaths = {};
            app.SelectedFilesLabel.Text = 'Click "Browse JSON Files" to select files.';
            app.CommandDisplay.Value = 'perceive.exe "" "" "" "" "" ""';

            % Set default values for the dropdowns
            app.ExtendedOutputDropDown.Items = {"", "yes"};
            app.ExtendedOutputDropDown.Value = "";

            app.GUIModeDropDown.Items = {"", "yes"};
            app.GUIModeDropDown.Value = "";

            % Update the command display
            app.updateCommandDisplay();
        end

        % Button pushed function: FilePickerValue
        function FilePickerValuePushed(app, event)
            % Open a file selection dialog for .json files with multi-select enabled
            [filenames, pathname] = uigetfile( ...
                {'*.json', 'JSON Files (*.json)'}, ...
                'Select one or more JSON files', ...
                'MultiSelect', 'on');

            % Handle case where user cancels the dialog
            if isequal(filenames, 0)
                app.SelectedFilesLabel.Text = 'No file selected.';
                % Reset the stored file paths
                app.SelectedFilePaths = {};
            else
                % Combine path and filenames into full paths
                if iscell(filenames)
                    app.SelectedFilePaths = cellfun(@(f) fullfile(pathname, f), filenames, 'UniformOutput', false);
                else
                    app.SelectedFilePaths = {fullfile(pathname, filenames)};
                end

                % Display the selected files in the label
                app.SelectedFilesLabel.Text = sprintf('Selected files (%d):\n%s', ...
                    length(app.SelectedFilePaths), ...
                    strjoin(app.SelectedFilePaths, '\n'));
            end

            % Update the command display
            app.updateCommandDisplay();
        end

        % Button pushed function: processfilesperceiveButton
        function processfilesperceiveButtonPushed(app, event)
            % Button callback to process the selected files
            % Validate inputs
            if ~app.validateInputs()
                return;
            end

            % Retrieve the additional arguments from the input fields
            sub = app.SubjectIDEditField.Value;
            sesMedOffOn01 = app.SessionEditField.Value;
            extended = app.ExtendedOutputDropDown.Value;
            gui = app.GUIModeDropDown.Value;
            localsettings_name = app.LocalSettingsNameEditField.Value;

            % Call perceive with the exact nomenclature
            if isempty(app.SelectedFilePaths)
                perceive('', sub, sesMedOffOn01, extended, gui, localsettings_name);
            elseif length(app.SelectedFilePaths) == 1
                perceive(app.SelectedFilePaths{1}, sub, sesMedOffOn01, extended, gui, localsettings_name);
            else
                perceive(app.SelectedFilePaths, sub, sesMedOffOn01, extended, gui, localsettings_name);
            end

        end

        % Value changed function: SubjectIDEditField
        function SubjectIDEditFieldValueChanged(app, event)
            app.updateCommandDisplay();
        end

        % Value changed function: SessionEditField
        function SessionEditFieldValueChanged(app, event)
            app.updateCommandDisplay();
        end

        % Value changed function: ExtendedOutputDropDown
        function ExtendedOutputDropDownValueChanged(app, event)
            app.updateCommandDisplay();
        end

        % Value changed function: GUIModeDropDown
        function GUIModeDropDownValueChanged(app, event)
            app.updateCommandDisplay();
        end

        % Value changed function: LocalSettingsNameEditField
        function LocalSettingsNameEditFieldValueChanged(app, event)
            app.updateCommandDisplay();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ExtendedOutputLabel = uilabel(app.UIFigure);
            app.ExtendedOutputLabel.Text = 'extended output';
            app.ExtendedOutputDropDown = uidropdown(app.UIFigure);
            app.ExtendedOutputDropDown.Items = {'', 'yes'};
            app.ExtendedOutputDropDown.ValueChangedFcn = createCallbackFcn(app, @ExtendedOutputDropDownValueChanged, true);
            app.ExtendedOutputDropDown.Value = '';
            app.FilePickerValue = uibutton(app.UIFigure, 'push');
            app.FilePickerValue.ButtonPushedFcn = createCallbackFcn(app, @FilePickerValuePushed, true);
            app.FilePickerValue.Text = 'Browse';
            app.outputdirectoryLabel = uilabel(app.UIFigure);
            app.outputdirectoryLabel.Text = 'json files:';
            app.outputDir = uieditfield(app.UIFigure, 'text');
            app.processfilesperceiveButton = uibutton(app.UIFigure, 'push');
            app.processfilesperceiveButton.ButtonPushedFcn = createCallbackFcn(app, @processfilesperceiveButtonPushed, true);
            app.processfilesperceiveButton.Text = 'process files perceive';
            app.SelectedFilesLabel = uilabel(app.UIFigure);
            app.SelectedFilesLabel.Text = 'no file selected';
            app.ToolboxLabel = uilabel(app.UIFigure);
            app.ToolboxLabel.Text = 'Perceive startup';
            app.perceiveGUIDropDownLabel = uilabel(app.UIFigure);
            app.perceiveGUIDropDownLabel.Text = 'perceive GUI';
            app.GUIModeDropDown = uidropdown(app.UIFigure);
            app.GUIModeDropDown.Items = {'', 'yes'};
            app.GUIModeDropDown.ValueChangedFcn = createCallbackFcn(app, @GUIModeDropDownValueChanged, true);
            app.GUIModeDropDown.Value = 'yes';
            app.subjectIDLabel = uilabel(app.UIFigure);
            app.subjectIDLabel.Text = 'subject ID';
            app.SubjectIDEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectIDEditField.ValueChangedFcn = createCallbackFcn(app, @SubjectIDEditFieldValueChanged, true);
            app.SubjectIDEditField.Placeholder = '(default)';
            app.subjectIDLabel_2 = uilabel(app.UIFigure);
            app.subjectIDLabel_2.Text = 'Session';
            app.SessionEditField = uieditfield(app.UIFigure, 'text');
            app.SessionEditField.ValueChangedFcn = createCallbackFcn(app, @SessionEditFieldValueChanged, true);
            app.SessionEditField.Placeholder = '(default)';
            app.commandlineTextAreaLabel = uilabel(app.UIFigure);
            app.commandlineTextAreaLabel.Text = 'commandline';
            app.CommandDisplay = uitextarea(app.UIFigure);
            app.CommandDisplay.Editable = 'off';
            app.LocalSettingsNameEditFieldLabel = uilabel(app.UIFigure);
            app.LocalSettingsNameEditFieldLabel.Text = 'LocalSettingsName';
            app.LocalSettingsNameEditField = uieditfield(app.UIFigure, 'text');
            app.LocalSettingsNameEditField.ValueChangedFcn = createCallbackFcn(app, @LocalSettingsNameEditFieldValueChanged, true);
            app.LocalSettingsNameEditField.Value = 'perceive_localsettings_default.json';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = perceive_gui_startup

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end