classdef update_tasks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FilecurrentlyselectedLabel  matlab.ui.control.Label
        partQuestion                matlab.ui.control.Label
        runQuestion                 matlab.ui.control.Label
        partDropDown                matlab.ui.control.DropDown
        partDropDownLabel           matlab.ui.control.Label
        runDropDown                 matlab.ui.control.DropDown
        runDropDownLabel            matlab.ui.control.Label
        redoButton                  matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        filenameLabel               matlab.ui.control.Label
        saveandcontinueButton       matlab.ui.control.Button
        taskDropDown                matlab.ui.control.DropDown
        taskDropDownLabel           matlab.ui.control.Label
        ContextMenu                 matlab.ui.container.ContextMenu
        testMenu                    matlab.ui.container.Menu
        stitchtogetherMenu          matlab.ui.container.Menu
    end

    properties (Access = public)
        MetaT
    end
    properties (Access = private)
       
        numberoffiles=0% Description
        iterator=1;
        
    end

    methods
        function nextrow(app)
            app.filenameLabel.Text = app.MetaT.perceiveFilename(app.iterator);
            app.taskDropDown.Value = app.MetaT.task{app.iterator};
            app.runDropDown.Value = app.MetaT.run{app.iterator};
            app.partDropDown.Value = app.MetaT.part{app.iterator};
            removeStyle(app.UITable)
            s = uistyle("BackgroundColor",[1 0.6 0.6]);
            row = [app.iterator;app.iterator;app.iterator];
            col = [2;5;7];
            addStyle(app.UITable,s,"cell",[row,col]);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MetaT)
            if ~exist('MetaT', 'var')
                load('MetaT.mat', 'MetaT')
            end
            app.MetaT = MetaT;
            app.UITable.Data = app.MetaT;
            app.UITable.ColumnWidth = 'fit';
            app.numberoffiles=length(app.MetaT.perceiveFilename);
            nextrow(app);
        end

        % Button pushed function: saveandcontinueButton
        function saveandcontinueButtonPushed(app, event)
            app.MetaT.task(app.iterator) = {app.taskDropDown.Value};
            app.MetaT.run(app.iterator) = {app.runDropDown.Value};
            if ~strcmp({app.partDropDown.Value},'None')
                 app.MetaT.part(app.iterator) = {app.partDropDown.Value};   
            end
            app.UITable.Data = app.MetaT;
            
            if app.iterator == app.numberoffiles
                for i = 1:app.numberoffiles
                    
                    task=regexp(app.MetaT.perceiveFilename{i},'(?<=task-).*(?=_acq)','match');
                    app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},task{1},app.MetaT.task{i});
                    run=regexp(app.MetaT.perceiveFilename{i},'(?<=run-).*(?=.mat)','match');
                    app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},run{1},app.MetaT.run{i});
                    if app.MetaT.part{i}
                        app.MetaT.perceiveFilename{i}=strrep(app.MetaT.perceiveFilename{i},'.mat',['_part-' app.MetaT.part{i} '.mat']);
                    end
                end
                
                problem_parts =0;
                for i = 1:app.numberoffiles
                    if strcmp(app.MetaT.part{i},'2') && ~problem_parts
                        hit1=app.MetaT.perceiveFilename{i};
                        assert(iscellstr(app.MetaT.perceiveFilename))
                        hit2=strrep(app.MetaT.perceiveFilename{i},'part-2.mat','part-1.mat');
                        found=sum(app.MetaT.perceiveFilename == string(hit2));
                        if found ~= 1
                            uiwait(msgbox('Files have a part 2, but not a corresponding part 1. Please ensure that task and run numbers are the same for recordings with 2 parts','Naming mistake','warn','modal'));
                            problem_parts =1;
                            break
                        end
                    end
                    if strcmp(app.MetaT.part{i},'1') && ~problem_parts
                        hit1=app.MetaT.perceiveFilename{i};
                        assert(iscellstr(app.MetaT.perceiveFilename))
                        hit2=strrep(app.MetaT.perceiveFilename{i},'part-1.mat','part-2.mat');
                        found=sum(app.MetaT.perceiveFilename == string(hit2));
                        if found ~= 1
                            errordlg('Files have a part 1, but not a corresponding part 2. Please ensure that task and run numbers are the same for recordings with 2 parts','File Error');
                            problem_parts =1;
                            break
                        end
                        hit3=strrep(app.MetaT.perceiveFilename{i},'_part-1','');
                        found=sum(app.MetaT.perceiveFilename == string(hit3));
                        if found > 0
                            errordlg(sprintf('There is 1 file that contains the same task and run numbers of those files after their concatenation. Please ensure that run numbers are unique. Please correct file: %s',hit3),'File Error');
                            problem_parts =1;
                            break
                        end

                        answer = questdlg(sprintf("These files will be concatenated: \n %s \n %s \n\n Ok?",hit1,hit2),'Stitching files', 'Proceed', 'Abort','Proceed');
                        if strcmp(answer,'Abort')
                            problem_parts =1;
                            break
                        end
                    end
                    
                end
                
                if problem_parts == 1
                    errordlg('Renaming was not BIDS-compliant, please correct where needed. (Default restart at first row).','Part Error');
                else
                    length(unique(app.MetaT.perceiveFilename)) ~= length(app.MetaT.perceiveFilename)
                    errordlg('Files have not unique names. Please adapt task and run numbers. (Default restart at first row).','Naming Error');
                end
                    
                if (length(unique(app.MetaT.perceiveFilename)) == length(app.MetaT.perceiveFilename)) && problem_parts == 0
                    app.saveandcontinueButton.UserData=1;
                else %something went wrong
                    app.iterator = 1;
                    app.UITable.Data = app.MetaT;
                    nextrow(app);
                    app.saveandcontinueButton.Text = 'Save and continue';
                end
            else
                app.iterator = app.iterator + 1;
                nextrow(app);
           
                if app.iterator == app.numberoffiles
                    app.saveandcontinueButton.Text = 'Evaluate filenaming';
                else
                    app.saveandcontinueButton.Text = 'Save and continue';
                end
            end

        end

        % Button pushed function: redoButton
        function redoButtonPushed(app, event)
            app.iterator = 1;
            nextrow(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.taskDropDownLabel = uilabel(app.UIFigure);
            app.taskDropDownLabel.Text = 'task';
            app.taskDropDown = uidropdown(app.UIFigure);
            app.taskDropDown.Items = {'Rest', 'RestTap', 'FingerTap', 'FingerTapL', 'FingerTapR', 'Rota', 'UPDRS', 'MovArtWalk', 'MovArtStand', 'MovArtHead', 'MovArtArms', 'FTGRampUpThres', 'FTGFastRamp125Hz', 'FTG110Hz', 'FTGBilateral', 'FTG145Hz', 'MID', 'SMTS', 'MSST', 'TASK1', 'TASK2', 'TASK3', 'TASK4', 'TASK5', 'TASK6', 'TASK7', 'TASK8', 'TASK9'};
            app.taskDropDown.Value = 'Rest';
            app.saveandcontinueButton = uibutton(app.UIFigure, 'push');
            app.saveandcontinueButton.ButtonPushedFcn = createCallbackFcn(app, @saveandcontinueButtonPushed, true);
            app.saveandcontinueButton.Text = 'save and continue';
            app.filenameLabel = uilabel(app.UIFigure);
            app.filenameLabel.Text = 'filename';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'''report'''; '''perceiveFilename'''; '''session'''; '''condition'''; '''task'''; '''contacts'''; '''run'''; '''part'''};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.Multiselect = 'off';
            app.UITable.Enable = 'off';
            app.redoButton = uibutton(app.UIFigure, 'push');
            app.redoButton.ButtonPushedFcn = createCallbackFcn(app, @redoButtonPushed, true);
            app.redoButton.Text = 'redo';
            app.runDropDownLabel = uilabel(app.UIFigure);
            app.runDropDownLabel.Text = 'run';
            app.runDropDown = uidropdown(app.UIFigure);
            app.runDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.runDropDown.Tooltip = {''};
            app.runDropDown.Value = '1';
            app.partDropDownLabel = uilabel(app.UIFigure);
            app.partDropDownLabel.Text = 'part';
            app.partDropDown = uidropdown(app.UIFigure);
            app.partDropDown.Items = {'None', '', '1', '2'};
            app.partDropDown.Tooltip = {''};
            app.partDropDown.Value = 'None';
            app.runQuestion = uilabel(app.UIFigure);
            app.runQuestion.Tooltip = {'A run number is always added, in contrast with the partnumber.'; ''; 'The runnumber is always 1 by default: for a new recording of the same subject, same condition, same task, same acquisition, increase the runnumber by 1.'; ''; 'For an interrupted recording, keep the runnumber equal and add or increase the part number instead (e.g. run 1 part 1, run 1 part 2).'; ''; 'See also https://bids.neuroimaging.io/'};
            app.runQuestion.Text = '?';
            app.partQuestion = uilabel(app.UIFigure);
            app.partQuestion.Tooltip = {'The partnumber is only applicable for recordings that were interrupted due to a technical issue.'; ''; 'partnumber of 0/None, means that the recording is complete (The partnumber will not appear).'; ''; 'Partnumber 1 and 2 means those recordings with the same task and run number needs to be stiched together [in that order] due to an interrumption. For now, parts of 3 cannot be stitched together.'; ''; 'Example:'; 'sub-002_ses-Fu12mMedOff02_task-Rest_acq-StimOff_mod-BrainSenseBip_run-1_part-1.mat'; 'sub-002_ses-Fu12mMedOff02_task-Rest_acq-StimOff_mod-BrainSenseBip_run-1_part-2.mat'; ''; 'will be concatenated to:'; 'sub-002_ses-Fu12mMedOff02_task-Rest_acq-StimOff_mod-BrainSenseBip_run-1.mat'; ''; 'The files of the 2 parts and the file of the concatenation are stored.'; ''; 'See also https://bids.neuroimaging.io/'};
            app.partQuestion.Text = '?';
            app.FilecurrentlyselectedLabel = uilabel(app.UIFigure);
            app.FilecurrentlyselectedLabel.Text = 'File currently selected:';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.testMenu = uimenu(app.ContextMenu);
            app.testMenu.Text = 'test';
            app.stitchtogetherMenu = uimenu(app.ContextMenu);
            app.stitchtogetherMenu.Text = 'stitch together';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = update_tasks(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end